classdef cheers < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CheerlightsAppUIFigure  matlab.ui.Figure
        CurrentcolorLabel       matlab.ui.control.Label
        GetcurrentcolorButton   matlab.ui.control.StateButton
        ColorPicker             matlab.ui.control.ColorPicker
        ColorPickerLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        color % current color
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            !pip install cheerlights_api
            app.color = struct(py.cheerlights_api.get_current_color());
        end

        % Value changed function: GetcurrentcolorButton
        function GetcurrentcolorButtonValueChanged(app, event)
            color_dict = struct(py.cheerlights_api.get_current_color());
            app.CurrentcolorLabel.Text = "Current color: "+ string(color_dict.color);
            rgb = double(py.cheerlights_api.hex_to_rgb(string(color_dict.hex)));
            app.ColorPicker.Value = rgb/255;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CheerlightsAppUIFigure = uifigure('Visible', 'off');
            app.CheerlightsAppUIFigure.Name = 'Cheerlights App';
            app.ColorPickerLabel = uilabel(app.CheerlightsAppUIFigure);
            app.ColorPickerLabel.Text = 'Color Picker';
            app.ColorPicker = uicolorpicker(app.CheerlightsAppUIFigure);
            app.ColorPicker.Value = [1 1 1];
            app.GetcurrentcolorButton = uibutton(app.CheerlightsAppUIFigure, 'state');
            app.GetcurrentcolorButton.ValueChangedFcn = createCallbackFcn(app, @GetcurrentcolorButtonValueChanged, true);
            app.GetcurrentcolorButton.Text = 'Get current color';
            app.CurrentcolorLabel = uilabel(app.CheerlightsAppUIFigure);
            app.CurrentcolorLabel.Text = 'Current color: ';
            % Show the figure after all components are created
            app.CheerlightsAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cheers

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CheerlightsAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CheerlightsAppUIFigure)
        end
    end
end